classdef ex8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        OutputTextArea              matlab.ui.control.TextArea
        OutputTextAreaLabel         matlab.ui.control.Label
        SelectimageButton           matlab.ui.control.Button
        StatisticalmeasureDropDown  matlab.ui.control.DropDown
        StatisticalmeasureDropDownLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        im = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectimageButton
        function SelectimageButtonPushed(app, event)
            [fname, fpath] = uigetfile( ...
                {'*.jpg;*png;*jpeg'}, ...
                "Select image" ...
             );
            app.im = imread([fpath, fname]);
            axis(app.UIAxes, 'off');
            app.UIAxes.Visible = "on";
            app.StatisticalmeasureDropDown.Enable = "on";
            app.StatisticalmeasureDropDownLabel.Enable = "on";
            app.OutputTextArea.Enable = "on";
            app.OutputTextAreaLabel.Enable = "on";
            imshow(app.im, 'Parent', app.UIAxes);
            title(app.UIAxes, 'Selected image');
        end

        % Value changed function: StatisticalmeasureDropDown
        function StatisticalmeasureDropDownValueChanged(app, event)
            value = app.StatisticalmeasureDropDown.Value;
            I = rgb2gray(app.im);
            [r, c] = size(I);
            I = double(I(:));
            switch (value)
                case "Choose"
                    app.OutputTextArea.Value = "";
                case "Min"
                    app.OutputTextArea.Value = num2str(min(I));
                case "Max"
                    app.OutputTextArea.Value = num2str(max(I));
                case "Mean"
                    app.OutputTextArea.Value = num2str(mean(I));
                case "Median"
                    app.OutputTextArea.Value = num2str(median(I));
                case "Mode"
                    app.OutputTextArea.Value = num2str(mode(I));
                case "Std deviation"
                    app.OutputTextArea.Value = num2str(std(I));
                case "Variance"
                    app.OutputTextArea.Value = num2str(var(I));
                case "GLCM props"
                    I = reshape(I, r, c);
                    [glcm, ~] = graycomatrix(I);
                    stats = graycoprops(glcm);
                    fs = fieldnames(stats);
                    op = "";
                    for i = 1:length(fs)
                        f = string(fs(i));
                        op = op + sprintf("%s: %.4f\n", f, stats.(f));
                    end
                    app.OutputTextArea.Value = op;
                otherwise
                    app.OutputTextArea.Value = "error: unknown option";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.StatisticalmeasureDropDownLabel = uilabel(app.UIFigure);
            app.StatisticalmeasureDropDownLabel.Enable = 'off';
            app.StatisticalmeasureDropDownLabel.Text = 'Statistical measure';
            app.StatisticalmeasureDropDown = uidropdown(app.UIFigure);
            app.StatisticalmeasureDropDown.Items = {'Choose', 'Min', 'Max', 'Mean', 'Median', 'Mode', 'Std deviation', 'Variance', 'GLCM props'};
            app.StatisticalmeasureDropDown.ValueChangedFcn = createCallbackFcn(app, @StatisticalmeasureDropDownValueChanged, true);
            app.StatisticalmeasureDropDown.Enable = 'off';
            app.StatisticalmeasureDropDown.Value = 'Choose';
            app.SelectimageButton = uibutton(app.UIFigure, 'push');
            app.SelectimageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectimageButtonPushed, true);
            app.SelectimageButton.Text = 'Select image';
            app.OutputTextAreaLabel = uilabel(app.UIFigure);
            app.OutputTextAreaLabel.Enable = 'off';
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.UIFigure);
            app.OutputTextArea.Editable = 'off';
            app.OutputTextArea.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ex8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end