classdef ex7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        OutputPanel        matlab.ui.container.Panel
        SelectImageButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            [fname, fpath] = uigetfile( ...
                {'*.jpg;*.jpeg;*.png'}, ...
                'Select file to open' ...
            );
            im = imread([fpath, fname]);
            im = rgb2gray(im);
            app.OutputPanel.AutoResizeChildren = 'off';
            ax1 = subplot(1, 3, 1, 'Parent', app.OutputPanel);
            imshow(im, 'Parent', ax1);
            title('Original image', 'Parent', ax1);
            ax2 = subplot(1, 3, 2, 'Parent', app.OutputPanel);
            imshow(imbinarize(im), 'Parent', ax2);
            title('Binary thresholded', 'Parent', ax2);
            ax3 = subplot(1, 3, 3, 'Parent', app.OutputPanel);
            thres = multithresh(im, 3);
            imshow(imquantize(im, thres), [], 'Parent', ax3);
            title('Multi-level thresholded', 'Parent', ax3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ex7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end